classdef LegsViewer3DFrom2DSlices < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        FilterGroup     matlab.ui.container.ButtonGroup
        ToutButton      matlab.ui.control.RadioButton
        OsButton        matlab.ui.control.RadioButton
        MusclesButton   matlab.ui.control.RadioButton
        PeauButton      matlab.ui.control.RadioButton
        LegStateButton  matlab.ui.control.StateButton
        MinSliderLabel  matlab.ui.control.Label
        MinSlider       matlab.ui.control.Slider
        MaxSliderLabel  matlab.ui.control.Label
        MaxSlider       matlab.ui.control.Slider
        UIAxes3DImage   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Début : Constantes
        CHEMIN_DOSSIER_JAMBE_CONTRACTEE = './EchantillonsTest/Contractee'
        CHEMIN_DOSSIER_JAMBE_REPOS = './EchantillonsTest/Repos'
        
        LONGUEUR_IMAGES = 513;
        LARGEUR_IMAGES = 513;
        
        ES_DILATATION = strel('disk',4);
        IMAGE_TEXTE = rgb2gray(imread('./Jambes/im_txt.png'));
        
        LEG_STATE_BUTTON_LABEL_0 = "Jambe au repos";
        LEG_STATE_BUTTON_LABEL_1 = "Jambe contractée";
        
        THRESHOLD_BONES = 200;
        
        FILTRE_AUCUN = 0;
        FILTRE_OS = 1;
        FILTRE_MUSCLES = 2;
        FILTRE_PEAU = 3;
        % Fin : Constantes
        
        % Début : Variables
        array3d_jambe_contractee;
        array3d_jambe_repos;
        
        leg_state = false;
        filtre_choisi;
        
        jambe_repos_start = 1;
        jambe_repos_end = 1;
        jambe_contractee_start = 1;
        jambe_contractee_end = 1;
        % Fin : Variables
    end
    
    methods (Access = private)
        
        function readLegsImages(app, noms_images_jambe_repos, noms_images_jambe_contractee)
            app.array3d_jambe_repos = zeros(app.LONGUEUR_IMAGES, app.LARGEUR_IMAGES, size(noms_images_jambe_repos,2));
            app.array3d_jambe_contractee = zeros(app.LONGUEUR_IMAGES, app.LARGEUR_IMAGES, size(noms_images_jambe_contractee,2));
            
            for coupe = 1 : size(noms_images_jambe_repos,2)
                app.array3d_jambe_repos(:,:,coupe) = rgb2gray(imread(app.CHEMIN_DOSSIER_JAMBE_REPOS + "/" + noms_images_jambe_repos{coupe}));
            end
            
            for coupe = 1 : size(noms_images_jambe_contractee,2)
                app.array3d_jambe_contractee(:,:,coupe) = rgb2gray(imread(app.CHEMIN_DOSSIER_JAMBE_CONTRACTEE + "/" + noms_images_jambe_contractee{coupe}));
            end
        end
        
        function display2DSlicesInArrayOnUIAxes(app)
            if(app.leg_state)
                if(app.filtre_choisi == app.FILTRE_AUCUN)
                    app.array3d_jambe_contractee = preProcess3DArray(app.array3d_jambe_contractee, app.IMAGE_TEXTE, app.ES_DILATATION);
                end
                if(app.filtre_choisi == app.FILTRE_OS)
                    app.array3d_jambe_contractee = os(app.array3d_jambe_contractee, app.THRESHOLD_BONES);
                end
                TSs = smooth3(app.array3d_jambe_contractee, 'box', 1);
            else
                if(app.filtre_choisi == app.FILTRE_AUCUN)
                    app.array3d_jambe_repos = preProcess3DArray(app.array3d_jambe_repos, app.IMAGE_TEXTE, app.ES_DILATATION);
                end
                if(app.filtre_choisi == app.FILTRE_OS)
                    app.array3d_jambe_repos = os(app.array3d_jambe_repos, app.THRESHOLD_BONES);
                end
                TSs = smooth3(app.array3d_jambe_repos, 'box', 1);
            end
                  
            % Construction avec isosurface
            patch(app.UIAxes3DImage, isosurface(TSs,0),'FaceColor', 'red','EdgeColor','none');
            patch(app.UIAxes3DImage, isocaps(TSs,1),'FaceColor','interp','EdgeColor','none');
            %isonormals(TSs,p);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [noms_images_jambe_repos,noms_images_jambe_contractee] = getLegsFileNames(app.CHEMIN_DOSSIER_JAMBE_REPOS,app.CHEMIN_DOSSIER_JAMBE_CONTRACTEE);
            readLegsImages(app, noms_images_jambe_repos, noms_images_jambe_contractee);
            
            app.jambe_repos_end = size(app.array3d_jambe_repos,3);
            app.jambe_contractee_end = size(app.array3d_jambe_repos,3);
            
            [app.array3d_jambe_repos] = preProcess3DArray(app.array3d_jambe_repos, app.IMAGE_TEXTE, app.ES_DILATATION);
            [app.array3d_jambe_contractee] = preProcess3DArray(app.array3d_jambe_contractee, app.IMAGE_TEXTE, app.ES_DILATATION);
            
            app.filtre_choisi = app.FILTRE_AUCUN;
            display2DSlicesInArrayOnUIAxes(app);
        end

        % Value changed function: LegStateButton
        function LegStateButtonValueChanged(app, event)
            state = app.LegStateButton.Value;
            app.leg_state = state;
            if(app.leg_state)
                app.LegStateButton.Text = app.LEG_STATE_BUTTON_LABEL_1;
            else
                app.LegStateButton.Text = app.LEG_STATE_BUTTON_LABEL_0;
            end
            display2DSlicesInArrayOnUIAxes(app)
        end

        % Selection changed function: FilterGroup
        function FilterGroupSelectionChanged(app, event)
            selectedButton = app.FilterGroup.SelectedObject;
            if(selectedButton == app.ToutButton)
                app.filtre_choisi = app.FILTRE_AUCUN;
            end
            
            if (selectedButton == app.OsButton)
                app.filtre_choisi = app.FILTRE_OS;
            end
            
            if (selectedButton == app.MusclesButton)
                app.filtre_choisi = app.FILTRE_MUSCLES;
            end
            
            if (selectedButton == app.PeauButton)
                app.filtre_choisi = app.FILTRE_PEAU;
            end
            display2DSlicesInArrayOnUIAxes(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FilterGroup = uibuttongroup(app.UIFigure);
            app.FilterGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterGroupSelectionChanged, true);
            app.FilterGroup.Title = 'Filtres';
            app.ToutButton = uiradiobutton(app.FilterGroup);
            app.ToutButton.Text = 'Tout';
            app.ToutButton.Value = true;
            app.OsButton = uiradiobutton(app.FilterGroup);
            app.OsButton.Text = 'Os';
            app.MusclesButton = uiradiobutton(app.FilterGroup);
            app.MusclesButton.Text = {'Muscles'; ''};
            app.PeauButton = uiradiobutton(app.FilterGroup);
            app.PeauButton.Text = 'Peau';
            app.LegStateButton = uibutton(app.UIFigure, 'state');
            app.LegStateButton.ValueChangedFcn = createCallbackFcn(app, @LegStateButtonValueChanged, true);
            app.LegStateButton.Text = 'Jambe au repos';
            app.MinSliderLabel = uilabel(app.UIFigure);
            app.MinSliderLabel.Text = 'Min';
            app.MinSlider = uislider(app.UIFigure);
            app.MaxSliderLabel = uilabel(app.UIFigure);
            app.MaxSliderLabel.Text = 'Max';
            app.MaxSlider = uislider(app.UIFigure);
            app.UIAxes3DImage = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3DImage, 'X')
            ylabel(app.UIAxes3DImage, 'Y')
            zlabel(app.UIAxes3DImage, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LegsViewer3DFrom2DSlices

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end